classdef BehaviorAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BehavioranalysisLabel           matlab.ui.control.Label
        BehavioralTestsPanel            matlab.ui.container.Panel
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        generalplottingButton           matlab.ui.control.RadioButton
        builtplottingButton_2           matlab.ui.control.RadioButton
        Namegroup2EditField             matlab.ui.control.EditField
        Namegroup2EditFieldLabel        matlab.ui.control.Label
        Namegroup1EditField             matlab.ui.control.EditField
        Namegroup1EditFieldLabel        matlab.ui.control.Label
        resultsButton                   matlab.ui.control.Button
        typetestDropDown                matlab.ui.control.DropDown
        typetestDropDownLabel           matlab.ui.control.Label
        loadconverteddataButton         matlab.ui.control.Button
        loaddatatoconvertButton         matlab.ui.control.Button
        InfraRedPanel                   matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        DropDown_2                      matlab.ui.control.DropDown
        ButtonGroup                     matlab.ui.container.ButtonGroup
        spesificfileButton              matlab.ui.control.RadioButton
        allfilesButton                  matlab.ui.control.RadioButton
        loaddataButton                  matlab.ui.control.Button
        baselinedaysEditField           matlab.ui.control.NumericEditField
        baselinedaysLabel               matlab.ui.control.Label
        baselinedaysEditField_2         matlab.ui.control.NumericEditField
        DropDown                        matlab.ui.control.DropDown
        MorePlotsButton                 matlab.ui.control.Button
        PlotActivityButton              matlab.ui.control.Button
        lightcyclecloset3DropDown       matlab.ui.control.DropDown
        lightcyclecloset3DropDownLabel  matlab.ui.control.Label
        lightcyclecloset2DropDown_2     matlab.ui.control.DropDown
        lightcyclecloset2DropDown_2Label  matlab.ui.control.Label
        choosepathtosavePanel           matlab.ui.container.Panel
        SaveplotsButton                 matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        clearButton                     matlab.ui.control.Button
    end


    properties (Access = public)
        path
        amount_of_files
        DATA % the rau data
        names
        Ta%type of light cycle for closet a
        Tb%type of light cycle for closet b
        numHours % number of 48-h periods (number of raws in actogram) or 24-h periods (number of raws in actogram)
        begin%nuber days of baseline in closet a&b

        newdata
        test_type
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.begin=[0,0];
        end

        % Button pushed function: loaddataButton
        function loaddataButtonPushed(app, event)
            [file,temppath] = uigetfile('*.csv',"select csv file",app.path);  %open the dialog box
            %datafile = fullfile(path, file); %path to the selected file
            if ~isempty(temppath)
                if app.allfilesButton.Value %to check if i want all the files in the path 
                    app.names=getFileList(temppath,'counts_',0,'anywhere');  % get names of data files
                    data=[];
                    for i=1:length(app.names)
                        data=[data;readtimetable(num2str(app.names{i}),'ReadVariableNames',0)];%change to timetable
                    end
                    app.DATA=timetable2table(data);
                else %i want just one file 
                    filepath=fullfile(temppath,file);%path for the file i want
                    singlef=readtimetable(filepath);%
                    app.DATA=timetable2table(singlef);%change to timetable
                end

                    msgbox('data was downloaded',"Success");
            else
                    msgbox('data was not downloaded',"Error");
            end
        end

        % Value changed function: lightcyclecloset2DropDown_2
        function lightcyclecloset2DropDown_2ValueChanged(app, event)
            value = app.lightcyclecloset2DropDown_2.Value;
            app.Ta=value;
            if app.Ta ~= "T24"
                app.baselinedaysEditField.Visible="on";
                app.baselinedaysLabel.Visible="on";
                app.DropDown_2.Visible="on";
                app.Lamp.Visible='on';
            end
        end

        % Value changed function: lightcyclecloset3DropDown
        function lightcyclecloset3DropDownValueChanged(app, event)
            value = app.lightcyclecloset3DropDown.Value;
            app.Tb=value;
            if app.Tb ~= "T24"
                app.baselinedaysEditField_2.Visible="on";
            end
        end

        % Button pushed function: PlotActivityButton
        function PlotActivityButtonPushed(app, event)
            switch app.numHours
                case "24 hours plot"
                    PlotTaVSTbn24(app.DATA,app.Ta,app.Tb,app.begin);
                case "48 hours plot"
                    PlotTaVSTbn48(app.DATA,app.Ta,app.Tb,app.begin);
            end
        end

        % Button pushed function: SaveplotsButton
        function SaveplotsButtonPushed(app, event)
            numFigs=2;
            for i=1:numFigs
                exportgraphics(figure(i), 'output.pdf', 'Append', true);
            end
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            clear 
            clc
        end

        % Button pushed function: loaddatatoconvertButton
        function loaddatatoconvertButtonPushed(app, event)
            [file,temppath] = uigetfile('*.csv',"select csv file",app.path);  %open the dialog box
            %datafile = fullfile(path, file); %path to the selected file
            if ~isempty(temppath)
                app.newdata = converter(temppath,file);
                msgbox('data was downloaded',"Success");
            else
                msgbox('data was not downloaded',"Error");
            end
        end

        % Value changed function: typetestDropDown
        function typetestDropDownValueChanged(app, event)
            value = app.typetestDropDown.Value;
            app.test_type=value;
        end

        % Button pushed function: resultsButton
        function resultsButtonPushed(app, event)
            switch app.test_type
                case 'OFT'
                    plotGroupComparison(app.newdata,'CT13','T24','OF','Duration','Distance','% center/total time')
                case 'EPM'
                    plotGroupComparison(app.newdata,'CT13','T24','EPM','Duration','Distance','% open hand/total')
                case 'SPT'
                    plotGroupComparison(app.newdata,'CT13','T24','SP','Right','Left','%suger pref')
                case 'SPT baseline'
                    plotGroupComparison(app.newdata,'CT13','T24','SP','Baseline','Baseline','%suger pref')
                case 'FST'
                    plotGroupComparison(app.newdata,'CT13','T24','FS','Duration','Frequency','%move/total')
                case 'All tests'
                    All_Plots(app.newdata);
            end
        end

        % Button pushed function: MorePlotsButton
        function MorePlotsButtonPushed(app, event)
            more_plots(app.DATA,app.Ta,app.Tb);
        end

        % Button pushed function: loadconverteddataButton
        function loadconverteddataButtonPushed(app, event)
            [file,temppath] = uigetfile('*.mat',"select mat file",app.path);  %open the dialog box
            if ~isempty(temppath)
                load(fullfile(temppath, file),'newdata');
                app.newdata=newdata;
                msgbox('data was downloaded',"Success");
            else
                msgbox('data was not downloaded',"Error");
            end
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.path = uigetdir();  %open the dialog box
            app.EditField.Value=app.path;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.numHours=value;
        end

        % Value changed function: baselinedaysEditField
        function baselinedaysEditFieldValueChanged(app, event)
            value = app.baselinedaysEditField.Value;
            app.begin(1)=value;
        end

        % Value changed function: baselinedaysEditField_2
        function baselinedaysEditField_2ValueChanged(app, event)
            value = app.baselinedaysEditField_2.Value;
            app.begin(2)=value;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
%             selectedButton = app.ButtonGroup.SelectedObject;
%             app.amount_of_files=selectedButton;
%             if app.amount_of_files=="all files"
%                 msgbox('data was downloaded',"Success");
%             else
%                 msgbox('data was not downloaded',"Error");
%             end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if value=='Blue'
                app.Lamp.Color='c';
            else
                app.Lamp.Color='y';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.choosepathtosavePanel = uipanel(app.UIFigure);
            app.choosepathtosavePanel.Title = 'choose path to save';
            app.BrowseButton = uibutton(app.choosepathtosavePanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.EditField = uieditfield(app.choosepathtosavePanel, 'text');
            app.SaveplotsButton = uibutton(app.choosepathtosavePanel, 'push');
            app.SaveplotsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveplotsButtonPushed, true);
            app.SaveplotsButton.Visible = 'off';
            app.SaveplotsButton.Text = 'Save plots';
            app.InfraRedPanel = uipanel(app.UIFigure);
            app.InfraRedPanel.Title = 'Infra Red';
            app.lightcyclecloset2DropDown_2Label = uilabel(app.InfraRedPanel);
            app.lightcyclecloset2DropDown_2Label.Text = 'light cycle closet 2';
            app.lightcyclecloset2DropDown_2 = uidropdown(app.InfraRedPanel);
            app.lightcyclecloset2DropDown_2.Items = {'T7', 'T6', 'T24', 'CT13', 'CT16', 'CT18', 'CT20'};
            app.lightcyclecloset2DropDown_2.ValueChangedFcn = createCallbackFcn(app, @lightcyclecloset2DropDown_2ValueChanged, true);
            app.lightcyclecloset2DropDown_2.Value = 'T7';
            app.lightcyclecloset3DropDownLabel = uilabel(app.InfraRedPanel);
            app.lightcyclecloset3DropDownLabel.Text = 'light cycle closet 3';
            app.lightcyclecloset3DropDown = uidropdown(app.InfraRedPanel);
            app.lightcyclecloset3DropDown.Items = {'T7', 'T6', 'T24', 'CT13', 'CT16', 'CT18', 'CT20'};
            app.lightcyclecloset3DropDown.ValueChangedFcn = createCallbackFcn(app, @lightcyclecloset3DropDownValueChanged, true);
            app.lightcyclecloset3DropDown.Value = 'T7';
            app.PlotActivityButton = uibutton(app.InfraRedPanel, 'push');
            app.PlotActivityButton.ButtonPushedFcn = createCallbackFcn(app, @PlotActivityButtonPushed, true);
            app.PlotActivityButton.Text = 'Plot Activity';
            app.MorePlotsButton = uibutton(app.InfraRedPanel, 'push');
            app.MorePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @MorePlotsButtonPushed, true);
            app.MorePlotsButton.Text = 'More Plots';
            app.DropDown = uidropdown(app.InfraRedPanel);
            app.DropDown.Items = {'choose type hour', '24 hours plot', '48 hours plot'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'choose type hour';
            app.baselinedaysEditField_2 = uieditfield(app.InfraRedPanel, 'numeric');
            app.baselinedaysEditField_2.ValueChangedFcn = createCallbackFcn(app, @baselinedaysEditField_2ValueChanged, true);
            app.baselinedaysEditField_2.Visible = 'off';
            app.baselinedaysLabel = uilabel(app.InfraRedPanel);
            app.baselinedaysLabel.Visible = 'off';
            app.baselinedaysLabel.Text = {'baseline '; 'days'};
            app.baselinedaysEditField = uieditfield(app.InfraRedPanel, 'numeric');
            app.baselinedaysEditField.ValueChangedFcn = createCallbackFcn(app, @baselinedaysEditFieldValueChanged, true);
            app.baselinedaysEditField.Visible = 'off';
            app.loaddataButton = uibutton(app.InfraRedPanel, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loaddataButtonPushed, true);
            app.loaddataButton.Text = 'load data';
            app.ButtonGroup = uibuttongroup(app.InfraRedPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.allfilesButton = uiradiobutton(app.ButtonGroup);
            app.allfilesButton.Text = 'all files';
            app.allfilesButton.Value = true;
            app.spesificfileButton = uiradiobutton(app.ButtonGroup);
            app.spesificfileButton.Text = 'spesific file';
            app.DropDown_2 = uidropdown(app.InfraRedPanel);
            app.DropDown_2.Items = {'colors', 'Blue', 'Yellow'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Visible = 'off';
            app.DropDown_2.Value = 'colors';
            app.Lamp = uilamp(app.InfraRedPanel);
            app.Lamp.Visible = 'off';
            app.BehavioralTestsPanel = uipanel(app.UIFigure);
            app.BehavioralTestsPanel.Title = 'Behavioral Tests';
            app.loaddatatoconvertButton = uibutton(app.BehavioralTestsPanel, 'push');
            app.loaddatatoconvertButton.ButtonPushedFcn = createCallbackFcn(app, @loaddatatoconvertButtonPushed, true);
            app.loaddatatoconvertButton.Text = 'load data to convert';
            app.loadconverteddataButton = uibutton(app.BehavioralTestsPanel, 'push');
            app.loadconverteddataButton.ButtonPushedFcn = createCallbackFcn(app, @loadconverteddataButtonPushed, true);
            app.loadconverteddataButton.Text = 'load converted data';
            app.typetestDropDownLabel = uilabel(app.BehavioralTestsPanel);
            app.typetestDropDownLabel.Text = 'type test';
            app.typetestDropDown = uidropdown(app.BehavioralTestsPanel);
            app.typetestDropDown.Items = {'choose test type ', 'OFT', 'EPM', 'SPT', 'SPT baseline', 'FST', 'All tests'};
            app.typetestDropDown.ValueChangedFcn = createCallbackFcn(app, @typetestDropDownValueChanged, true);
            app.typetestDropDown.Value = 'choose test type ';
            app.resultsButton = uibutton(app.BehavioralTestsPanel, 'push');
            app.resultsButton.ButtonPushedFcn = createCallbackFcn(app, @resultsButtonPushed, true);
            app.resultsButton.Text = 'results';
            app.Namegroup1EditFieldLabel = uilabel(app.BehavioralTestsPanel);
            app.Namegroup1EditFieldLabel.Visible = 'off';
            app.Namegroup1EditFieldLabel.Text = 'Name group 1: ';
            app.Namegroup1EditField = uieditfield(app.BehavioralTestsPanel, 'text');
            app.Namegroup1EditField.Visible = 'off';
            app.Namegroup2EditFieldLabel = uilabel(app.BehavioralTestsPanel);
            app.Namegroup2EditFieldLabel.Visible = 'off';
            app.Namegroup2EditFieldLabel.Text = 'Name group 2:';
            app.Namegroup2EditField = uieditfield(app.BehavioralTestsPanel, 'text');
            app.Namegroup2EditField.Visible = 'off';
            app.ButtonGroup_2 = uibuttongroup(app.BehavioralTestsPanel);
            app.builtplottingButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.builtplottingButton_2.Text = 'built plotting';
            app.builtplottingButton_2.Value = true;
            app.generalplottingButton = uiradiobutton(app.ButtonGroup_2);
            app.generalplottingButton.Text = 'general plotting';
            app.BehavioranalysisLabel = uilabel(app.UIFigure);
            app.BehavioranalysisLabel.Text = 'Behavior analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BehaviorAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end